classdef appSpiderPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ConfigPanel              matlab.ui.container.Panel
        MinimumVoxPercEditField  matlab.ui.control.NumericEditField
        MinimumLabel             matlab.ui.control.Label
        ThresholdtypeDropDown    matlab.ui.control.DropDown
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        ROIsEditField            matlab.ui.control.EditField
        ROIsEditFieldLabel       matlab.ui.control.Label
        AtlasEditField           matlab.ui.control.EditField
        AtlasEditFieldLabel      matlab.ui.control.Label
        PimgEditField            matlab.ui.control.EditField
        PimgEditFieldLabel       matlab.ui.control.Label
        PaddimgEditField         matlab.ui.control.EditField
        PaddimgEditFieldLabel    matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        PlotPanel                matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pimg
        handles
        atlas % atlas image
        roiList % atlas regions csv
        mniIDX1
        mniIDX2
        options
        meas
        measind
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles)
            % temp should always be the current visualisation image
            app.pimg = 'temp.nii';
            app.handles = handles; 
            % for now, this is the default atlas
            app.atlas = sprintf('%s/interpretable/anatomical/AAL3.nii', handles.NM.defs.paths);
            app.roiList = sprintf('%s/interpretable/anatomical/AAL3.csv', handles.NM.defs.paths);
            app.mniIDX1 = 1;
            app.mniIDX2 = 2;


            % user input
            app.options = struct;
            app.options.vxcheck = 1;
            app.options.thresh = app.ThresholdEditField.Value;
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;

            app.options.name = handles.NM.analysis{1,handles.curranal}.rootdir; % does rootdir exist?

            app.measind = handles.selVisMeas.Value;
            app.meas = handles.selVisMeas.String;
            
            switch app.meas{app.measind}
                case 'Feature weights [Overall Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_overallM';

                case 'Feature weights [Grand Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_grandM';

                case 'CV-ratio of feature weights [Overall Mean]'
                    app.options.pattern_name = 'CV_featWeights_overallM';

                case 'CV-ratio of feature weights [Grand Mean]'
                    app.options.pattern_name = 'CV_featWeights_grandM';

                case 'Probability of feature reliability (95%-CI) [Grand Mean]'
                    app.options.pattern_name = 'ProbFeatRel95_grandM';

                case 'Sign-based consistency'
                    app.options.pattern_name = 'SignBsdConsistency';

                case 'Sign-based consistency (Z score)'
                    app.options.pattern_name = 'SignBsdConsistency_ZScore';

                case 'Sign-based consistency -log10(P value)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval';

                case 'Sign-based consistency -log10(P value, FDR)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval_FDR';
                otherwise
                    app.options.pattern_name = meas{measind};
            end

            app.options.data = [];
            app.options.compute_voxelstats = 0;
            
            [vROI, tROI, yROI, yT] = nk_aal_volume_roi(app.pimg, ...
                [], ...
                app.atlas, ...
                app.roiList, ...
                app.mniIDX1, ...
                app.mniIDX2, ...
                app.options);

            rois = readtable(app.roiList); % should be in this folder in NM (later: user can define atlas) 
            plot_spider_plot(tROI,rois, app.UIAxes);

%             rois = readtable('visual/AAL2.csv'); % should be in this folder in NM (later: user can define atlas) 
%             tROI.Properties.RowNames = tROI.AnatomicalRegion;
% 
%             
%             for i=1:height(rois)
%                 lbl = rois.Var2{i};
%                 try rois.tROI(i) = table2array(tROI(lbl, 6)); catch; end
%             end
%             rois(rois.tROI==0,:) = [];
%             P = rois.tROI';
% %             y=120;
% %             
%             spider_plot(P, ...
%                 'axesLabels', rois.Var2,...
%                 'axesLimits', [zeros(1,y); 100*ones(1,y)], ...
%                 'axesInterval', 1, ...
%                 'axesLabelsEdge','none', ...
%                 'axesDisplay', 'one', ...
%                 'axesLabelsOffset', 0.05, ...
%                 'axesFontSize', 12,...
%                 'labelFontSize', 9, ...
%                 'lineWidth',2,...
%                 'fillOption', 'on',...
%                 'markerSize', 18,...
%                 'axesHorzAlign', 'quadrant', ...
%                 'axesVertAlign', 'quadrant', ...
%                 'axesLabelsRotate', 'on', ...
%                 'axesDirection', 'normal', ...
%                 'axesPrecision',0,...
%                 'axesZero','on',...
%                 'axesLabelsRotate','on',...
%                 'axesOffset',1, ...
%                 'axesobject', app.UIAxes);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.options = struct;
            app.options.vxcheck = 1;
            app.options.thresh = app.ThresholdEditField.Value;
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;
            app.options.name = app.handles.NM.analysis{1,app.handles.curranal}.rootdir; % does rootdir exist?

           
            switch app.meas{app.measind}
                case 'Feature weights [Overall Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_overallM';

                case 'Feature weights [Grand Mean (StErr)]'
                    app.options.pattern_name = 'FeatWeights_grandM';

                case 'CV-ratio of feature weights [Overall Mean]'
                    app.options.pattern_name = 'CV_featWeights_overallM';

                case 'CV-ratio of feature weights [Grand Mean]'
                    app.options.pattern_name = 'CV_featWeights_grandM';

                case 'Probability of feature reliability (95%-CI) [Grand Mean]'
                    app.options.pattern_name = 'ProbFeatRel95_grandM';

                case 'Sign-based consistency'
                    app.options.pattern_name = 'SignBsdConsistency';

                case 'Sign-based consistency (Z score)'
                    app.options.pattern_name = 'SignBsdConsistency_ZScore';

                case 'Sign-based consistency -log10(P value)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval';

                case 'Sign-based consistency -log10(P value, FDR)'
                    app.options.pattern_name = 'SignBsdConsistency_log10Pval_FDR';
                otherwise
                    app.options.pattern_name = meas{measind};
            end

            app.options.data = [];
            app.options.compute_voxelstats = 0;
            
            
            [vROI, tROI, yROI, yT] = nk_aal_volume_roi(app.pimg, ...
                [], ...
                app.atlas, ...
                app.roiList, ...
                app.mniIDX1, ...
                app.mniIDX2, ...
                app.options);

            rois = readtable(app.roiList); 
            plot_spider_plot(tROI,rois, app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {589, 589};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Tooltip = {''};
            app.ConfigPanel.Title = 'Configuration';
            app.PlotButton = uibutton(app.ConfigPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PaddimgEditFieldLabel = uilabel(app.ConfigPanel);
            app.PaddimgEditFieldLabel.Enable = 'off';
            app.PaddimgEditFieldLabel.Text = 'Paddimg';
            app.PaddimgEditField = uieditfield(app.ConfigPanel, 'text');
            app.PaddimgEditField.Editable = 'off';
            app.PaddimgEditField.Enable = 'off';
            app.PaddimgEditField.Tooltip = {'(optional) paths to additional statistical images to be co-analyzed with Pimg <char array>'};
            app.PaddimgEditField.Value = 'optional';
            app.PimgEditFieldLabel = uilabel(app.ConfigPanel);
            app.PimgEditFieldLabel.Enable = 'off';
            app.PimgEditFieldLabel.Text = 'Pimg';
            app.PimgEditField = uieditfield(app.ConfigPanel, 'text');
            app.PimgEditField.Editable = 'off';
            app.PimgEditField.Enable = 'off';
            app.PimgEditField.Tooltip = {'paths to statistical images <char array>'};
            app.AtlasEditFieldLabel = uilabel(app.ConfigPanel);
            app.AtlasEditFieldLabel.Enable = 'off';
            app.AtlasEditFieldLabel.Text = 'Atlas';
            app.AtlasEditField = uieditfield(app.ConfigPanel, 'text');
            app.AtlasEditField.Editable = 'off';
            app.AtlasEditField.Enable = 'off';
            app.AtlasEditField.Tooltip = {'path to parcellation image <char>'};
            app.ROIsEditFieldLabel = uilabel(app.ConfigPanel);
            app.ROIsEditFieldLabel.Enable = 'off';
            app.ROIsEditFieldLabel.Text = 'ROIs';
            app.ROIsEditField = uieditfield(app.ConfigPanel, 'text');
            app.ROIsEditField.Editable = 'off';
            app.ROIsEditField.Enable = 'off';
            app.ROIsEditField.Tooltip = {'path to csv or excel file with parcellation info, which should contain the the id column indexing parcellations '; 'in the parcellation image <char>'};
            app.ThresholdEditFieldLabel = uilabel(app.ConfigPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ConfigPanel, 'numeric');
            app.ThresholdtypeDropDown = uidropdown(app.ConfigPanel);
            app.ThresholdtypeDropDown.Items = {'>', '>=', '<', '<='};
            app.ThresholdtypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ThresholdtypeDropDown.Value = '1';
            app.MinimumLabel = uilabel(app.ConfigPanel);
            app.MinimumLabel.Tooltip = {''};
            app.MinimumLabel.Text = 'Minimum % of voxels in ROI  ';
            app.MinimumVoxPercEditField = uieditfield(app.ConfigPanel, 'numeric');
            app.MinimumVoxPercEditField.Value = 1;
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Spider Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSpiderPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end